classdef CI_ImagingLIF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CI_ImagingLIFUIFigure  matlab.ui.Figure
        TabGroup2              matlab.ui.container.TabGroup
        LASXFilesTab           matlab.ui.container.Tab
        ViewPanel              matlab.ui.container.Panel
        ShowButton             matlab.ui.control.StateButton
        MaxProjButton          matlab.ui.control.StateButton
        AutoContrButton        matlab.ui.control.StateButton
        ExpandNoneButton       matlab.ui.control.Button
        ExpandAllButton        matlab.ui.control.Button
        CloseSelectedFile      matlab.ui.control.Button
        Tree                   matlab.ui.container.Tree
        Node                   matlab.ui.container.TreeNode
        SelectFile             matlab.ui.control.Button
        MoredetailsCheckBox    matlab.ui.control.CheckBox
        LogLabel               matlab.ui.control.Label
        LogBox                 matlab.ui.control.ListBox
        TabGroup               matlab.ui.container.TabGroup
        ImageTab               matlab.ui.container.Tab
        AllButton              matlab.ui.control.Button
        Ch_9                   matlab.ui.control.StateButton
        Ch_8                   matlab.ui.control.StateButton
        Ch_7                   matlab.ui.control.StateButton
        Ch_6                   matlab.ui.control.StateButton
        Ch_5                   matlab.ui.control.StateButton
        Ch_4                   matlab.ui.control.StateButton
        Ch_3                   matlab.ui.control.StateButton
        Ch_2                   matlab.ui.control.StateButton
        Ch_1                   matlab.ui.control.StateButton
        ZSlider                matlab.ui.control.Slider
        TileSlider             matlab.ui.control.Slider
        TimeSlider             matlab.ui.control.Slider
        UIAxes_ch9             matlab.ui.control.UIAxes
        UIAxes_ch8             matlab.ui.control.UIAxes
        UIAxes_ch7             matlab.ui.control.UIAxes
        UIAxes_ch6             matlab.ui.control.UIAxes
        UIAxes_ch5             matlab.ui.control.UIAxes
        UIAxes_ch4             matlab.ui.control.UIAxes
        UIAxes_ch3             matlab.ui.control.UIAxes
        UIAxes_ch2             matlab.ui.control.UIAxes
        UIAxes_ch1             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        isNotLASX=false
        
        filetype=''                 % lif or xlef
        filepath=''                 % filepath of lif/xlef file
        filename=''                 % lif/xlef file name
        filebasename=''             % lif/xlef file name without extension
        file=''                     % lif/xlef fullfile name (including filepath)
        
        clifinfo=[]                 % current (single) selected file
        
        Nodes  matlab.ui.container.TreeNode  % Nodes for treeview
    end
    
    properties (Access = private)
        isStarting=true     % Check if starting up
    end
    
    methods (Access = private)
        
        function ReadLeicaLIF(app)
            app.Tree.Enable='off';
            %app.Node.delete
            fileID = fopen(app.file,'r','n','UTF-8');
            testvalue=fread(fileID,1,'int32');
            if testvalue~=112; return; end 
            XMLContentLenght=fread(fileID,1,'int32'); %#ok<NASGU> 
            testvalue = fread(fileID,1,'uint8');
            if testvalue~=42; return; end 
            testvalue=fread(fileID,1,'int32');
            uc=reshape(fread(fileID,2*testvalue,'*char'),1,testvalue*2);
            XMLObjDescription=cfUnicode2ascii(uc);
            XMLObjDescription=regexprep(XMLObjDescription,'</',[newline '</']);
            %Remove error from GSD LIF File
            errstrings=extractBetween(XMLObjDescription,'High resolution image created from &quot;','&quot;.');
            XMLObjDescription=replace(XMLObjDescription,errstrings,'');
            
            %Read memory blocks
            lifinfo=struct;
            tel=0;
            while ~feof(fileID)
                tel=tel+1;
                testvalue=fread(fileID,1,'int32');
                if feof(fileID); break; end
                if testvalue ~=112; return; end
                BinContentLenght=fread(fileID,1,'int32'); %#ok<NASGU> 
                testvalue = fread(fileID,1,'uint8');
                if testvalue~=42; return; end 
                MemorySize=fread(fileID,1,'int64');
                testvalue = fread(fileID,1,'uint8');
                if testvalue~=42; return; end 
                testvalue=fread(fileID,1,'int32');
                sBlockID=cfUnicode2ascii(reshape(fread(fileID,2*testvalue,'*char'),1,testvalue*2));
                lifinfo(tel).BlockID=sBlockID;
                lifinfo(tel).MemorySize=MemorySize;
                lifinfo(tel).Position=ftell(fileID);
                if MemorySize>0; fseek(fileID,MemorySize,0); end
            end
            fclose(fileID);
            setLogD(app,'Opening LIF-File');
            s=xml2structstring(['<?xml version="1.0" encoding="ISO-8859-1"?>' XMLObjDescription]);
            app.Node = uitreenode(app.Tree,'Text',app.filename,'NodeData',fileparts(app.file), 'Icon','LAS-X.png','Tag','File');
            setLogD(app,'LAS-X LIF File Opened');
            cnode=0;
            elements=numel(s.LMSDataContainerHeader.Element.Children.Element);
            setLogD(app,[num2str(elements) ' root nodes found, scanning...']);
            for k = 1:elements
                cnode=cnode+1;
                if elements==1
                    thiselement=s.LMSDataContainerHeader.Element.Children.Element;
                else
                    thiselement=s.LMSDataContainerHeader.Element.Children.Element{k};
                end
                MemoryBlockID=thiselement.Memory.Attributes.MemoryBlockID;
                lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                MemorySize=str2double(thiselement.Memory.Attributes.Size);
                name=thiselement.Attributes.Name;
                lifinfo(lifinfoindex).filetype=app.filetype;
                lifinfo(lifinfoindex).filepath=app.filepath;
                lifinfo(lifinfoindex).LIFFile=app.file;
                lifinfo(lifinfoindex).name=name;
                if MemorySize==0 && ~contains(name,'iomanagerconfiguation','IgnoreCase',true)
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',name,'Icon','folder.png','Tag','Folder');
                    elements1=numel(thiselement.Children.Element);
                    cnode1=cnode;
                    for k1 = 1:elements1
                        cnode=cnode+1;
                        if elements1==1
                            thiselement1 = thiselement.Children.Element;
                        else
                            thiselement1 = thiselement.Children.Element{k1};
                        end
                        MemoryBlockID=thiselement1.Memory.Attributes.MemoryBlockID;
                        lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                        MemorySize=str2double(thiselement1.Memory.Attributes.Size);
                        name1=thiselement1.Attributes.Name;
                        lifinfo(lifinfoindex).filetype=app.filetype;
                        lifinfo(lifinfoindex).filepath=app.filepath;
                        lifinfo(lifinfoindex).LIFFile=app.file;
                        lifinfo(lifinfoindex).name=name1;
                        if MemorySize==0 && ~contains(name,'iomanagerconfiguation','IgnoreCase',true)
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',name1,'Icon','folder.png','Tag','Folder');
                            elements2=numel(thiselement1.Children.Element);
                            cnode2=cnode;
                            for k2 = 1:elements2
                                cnode=cnode+1;
                                if elements2==1
                                    thiselement2 = thiselement1.Children.Element;
                                else
                                    thiselement2 = thiselement1.Children.Element{k2};
                                end
                                MemoryBlockID=thiselement2.Memory.Attributes.MemoryBlockID;
                                lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                                MemorySize=str2double(thiselement2.Memory.Attributes.Size);
                                name2=thiselement2.Attributes.Name;
                                lifinfo(lifinfoindex).name=name2;
                                if MemorySize==0 && ~contains(name,'iomanagerconfiguation','IgnoreCase',true)
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',name2,'Icon','folder.png','Tag','Folder');
                                    elements3=numel(thiselement2.Children.Element);
                                    cnode3=cnode;
                                    for k3 = 1:elements3
                                        cnode=cnode+1;
                                        if elements3==1
                                            thiselement3 = thiselement2.Children.Element;
                                        else
                                            thiselement3 = thiselement2.Children.Element{k3};
                                        end
                                        MemoryBlockID=thiselement3.Memory.Attributes.MemoryBlockID;
                                        lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                                        MemorySize=str2double(thiselement3.Memory.Attributes.Size);
                                        name3=thiselement3.Attributes.Name;
                                        lifinfo(lifinfoindex).name=name3;
                                        if MemorySize==0 && ~contains(name,'iomanagerconfiguation','IgnoreCase',true)
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',name3,'Icon','folder.png','Tag','Folder');
                                        elseif ~contains(name3,'iomanagerconfiguation','IgnoreCase',true) && ~contains(name3,'environmentalgraph','IgnoreCase',true)
                                            lifinfo(lifinfoindex).filetype=app.filetype;
                                            lifinfo(lifinfoindex).filepath=app.filepath;
                                            lifinfo(lifinfoindex).LIFFile=app.file;
                                            lifinfo(lifinfoindex).name=name3;
                                            if isfield(thiselement3.Data,'Image') 
                                                lifinfo(lifinfoindex).datatype='Image';
                                                lifinfo(lifinfoindex).Image=thiselement3.Data.Image;
                                            elseif isfield(thiselement3.Data,'GISTEventList')
                                                lifinfo(lifinfoindex).datatype='Eventlist';
                                                lifinfo(lifinfoindex).GISTEventList=thiselement3.Data.GISTEventList;
                                            else
                                                lifinfo(lifinfoindex).datatype='unknown';
                                            end
                                            lifinfo(lifinfoindex).Parent=[app.Nodes(cnode3).Parent.Parent.Text '_' app.Nodes(cnode3).Parent.Text '_' app.Nodes(cnode3).Text];
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',{name3, lifinfo(lifinfoindex)},'Icon','image.png','Tag',lifinfo(lifinfoindex).datatype,'UserData',cnode);
                                        else
                                            cnode=cnode-1;
                                        end
                                    end
                                elseif ~contains(name2,'iomanagerconfiguation','IgnoreCase',true) && ~contains(name2,'environmentalgraph','IgnoreCase',true)
                                    lifinfo(lifinfoindex).filetype=app.filetype;
                                    lifinfo(lifinfoindex).filepath=app.filepath;
                                    lifinfo(lifinfoindex).LIFFile=app.file;
                                    lifinfo(lifinfoindex).name=name2;
                                    if isfield(thiselement2.Data,'Image') 
                                        lifinfo(lifinfoindex).datatype='Image';
                                        lifinfo(lifinfoindex).Image=thiselement2.Data.Image;
                                    elseif isfield(thiselement2.Data,'GISTEventList')
                                        lifinfo(lifinfoindex).datatype='Eventlist';
                                        lifinfo(lifinfoindex).GISTEventList=thiselement2.Data.GISTEventList;
                                    else
                                        lifinfo(lifinfoindex).datatype='unknown';
                                    end
                                    lifinfo(lifinfoindex).Parent=[app.Nodes(cnode2).Parent.Text '_' app.Nodes(cnode2).Text];
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',{name2, lifinfo(lifinfoindex)},'Icon','image.png','Tag',lifinfo(lifinfoindex).datatype,'UserData',cnode);
                                else
                                    cnode=cnode-1;
                                end
                            end
                        elseif ~contains(name1,'iomanagerconfiguation','IgnoreCase',true) && ~contains(name1,'environmentalgraph','IgnoreCase',true)
                            if isfield(thiselement1.Data,'Image') 
                                lifinfo(lifinfoindex).datatype='Image';
                                lifinfo(lifinfoindex).Image=thiselement1.Data.Image;
                            elseif isfield(thiselement1.Data,'GISTEventList')
                                lifinfo(lifinfoindex).datatype='Eventlist';
                                lifinfo(lifinfoindex).GISTEventList=thiselement1.Data.GISTEventList;
                            else
                                lifinfo(lifinfoindex).datatype='unknown';
                            end
                            lifinfo(lifinfoindex).Parent=app.Nodes(cnode1).Text;
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',{name1, lifinfo(lifinfoindex)},'Icon','image.png','Tag',lifinfo(lifinfoindex).datatype,'UserData',cnode);
                        else
                            cnode=cnode-1;
                        end
                    end
                elseif ~contains(name,'iomanagerconfiguation','IgnoreCase',true) && ~contains(name,'environmentalgraph','IgnoreCase',true)
                    if isfield(thiselement.Data,'Image') 
                        lifinfo(lifinfoindex).datatype='Image';
                        lifinfo(lifinfoindex).Image=thiselement.Data.Image;
                    elseif isfield(thiselement.Data,'GISTEventList')
                        lifinfo(lifinfoindex).datatype='Eventlist';
                        lifinfo(lifinfoindex).GISTEventList=thiselement.Data.GISTEventList;
                    else
                        lifinfo(lifinfoindex).datatype='unknown';
                    end
                    lifinfo(lifinfoindex).Parent='';
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',{name, lifinfo(lifinfoindex)},'Icon','image.png','Tag',lifinfo(lifinfoindex).datatype,'UserData',cnode);
                else
                    cnode=cnode-1;
                end
            end
            
            setLogD(app,'Ready reading LIF file');
            app.Tree.Enable='on';
            %expand(app.Tree,'all');
        end    
    
        function ReadLeicaXLEF(app)
            app.Tree.Enable='off';            
            %app.Node.delete;
            xDoc = xmlread(app.file);
            app.Node = uitreenode(app.Tree,'Text',app.filename,'NodeData',fileparts(app.file), 'Icon','LAS-X.png','Tag','File');
            setLogD(app,'Las-X XLEF File Opened');
            xmlInfo = xDoc.getElementsByTagName('Reference');
            cnode=0;
            setLogD(app,[num2str(xmlInfo.getLength-1) ' reference nodes found']);
            for k = 0:xmlInfo.getLength-1
                cnode=cnode+1;
                thisInfo = xmlInfo.item(k);
                ref=getXLfile(app, char(thisInfo.getAttribute('File')),'');
                [~,name,ext]=fileparts(ref);
                lifinfo.name=name;
                lifinfo.filetype=app.filetype;
                lifinfo.filepath=app.filepath;
                lifinfo.XLIFFile=ref;
                lifinfo.LIFFile=ref;
                lifinfo.LOFFile=getLOFfile(app, ref);
                if strcmpi(ext,'.xlcf')
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',ref,'Icon','folder.png','Tag','Folder');
                    xDoc1 = xmlread(ref);
                    xmlInfo1 = xDoc1.getElementsByTagName('Reference');
                    cnode1=cnode;
                    for k1 = 0:xmlInfo1.getLength-1
                        cnode=cnode+1;
                        thisInfo1 = xmlInfo1.item(k1);
                        ref1=getXLfile(app, char(thisInfo1.getAttribute('File')),ref);
                        [~,name1,ext1]=fileparts(ref1);
                        lifinfo.name=name1;
                        lifinfo.filetype=app.filetype;
                        lifinfo.filepath=app.filepath;
                        lifinfo.XLIFFile=ref1;
                        lifinfo.LIFFile=ref1;
                        lifinfo.LOFFile=getLOFfile(app, ref1);
                        if strcmpi(ext1,'.xlcf')
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',ref1,'Icon','folder.png','Tag','Folder');
                            xDoc2 = xmlread(ref1);
                            xmlInfo2 = xDoc2.getElementsByTagName('Reference');
                            cnode2=cnode;
                            for k2 = 0:xmlInfo2.getLength-1
                                cnode=cnode+1;
                                thisInfo2 = xmlInfo2.item(k2);
                                ref2=getXLfile(app, char(thisInfo2.getAttribute('File')),ref1);
                                [~,name2,ext2]=fileparts(ref2);
                                lifinfo.name=name2;
                                lifinfo.filetype=app.filetype;
                                lifinfo.filepath=app.filepath;
                                lifinfo.XLIFFile=ref2;
                                lifinfo.LIFFile=ref2;
                                lifinfo.LOFFile=getLOFfile(app, ref2);
                                if strcmpi(ext2,'.xlcf')
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',ref2,'Icon','folder.png','Tag','Folder');
                                    xDoc3 = xmlread(ref2);
                                    xmlInfo3 = xDoc3.getElementsByTagName('Reference');
                                    cnode3=cnode;
                                    for k3 = 0:xmlInfo3.getLength-1
                                        cnode=cnode+1;
                                        thisInfo3 = xmlInfo3.item(k3);
                                        ref3=getXLfile(app, char(thisInfo3.getAttribute('File')),ref2);
                                        [~,name3,ext3]=fileparts(ref3);
                                        lifinfo.name=name3;
                                        lifinfo.filetype=app.filetype;
                                        lifinfo.filepath=app.filepath;
                                        lifinfo.XLIFFile=ref3;
                                        lifinfo.LIFFile=ref3;
                                        lifinfo.LOFFile=getLOFfile(app, ref3);
                                        if strcmpi(ext3,'.xlcf')
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',ref3,'Icon','folder.png','Tag','Folder');
                                        elseif ~contains(name3,'iomanagerconfiguation','IgnoreCase',true) && ~contains(name3,'environmentalgraph','IgnoreCase',true)
                                            s=xml2struct(lifinfo.XLIFFile);
                                            lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                                            lifinfo.datatype='Image';
                                            lifinfo.Parent=[app.Nodes(cnode3).Parent.Parent.Text '_' app.Nodes(cnode3).Parent.Text '_' app.Nodes(cnode3).Text];
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',{ref3,lifinfo},'Icon','image.png','Tag',lifinfo.datatype,'UserData',cnode);
                                        else
                                            cnode=cnode-1;
                                        end
                                    end
                                elseif ~contains(name2,'iomanagerconfiguation','IgnoreCase',true) && ~contains(name2,'environmentalgraph','IgnoreCase',true)
                                    s=xml2struct(lifinfo.XLIFFile);
                                    lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                                    lifinfo.datatype='Image';
                                    lifinfo.Parent=[app.Nodes(cnode2).Parent.Text '_' app.Nodes(cnode2).Text];
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',{ref2,lifinfo},'Icon','image.png','Tag',lifinfo.datatype,'UserData',cnode);
                                else
                                    cnode=cnode-1;
                                end
                            end
                        elseif ~contains(name1,'iomanagerconfiguation','IgnoreCase',true) && ~contains(name1,'environmentalgraph','IgnoreCase',true)
                            s=xml2struct(lifinfo.XLIFFile);
                            lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                            lifinfo.datatype='Image';
                            lifinfo.Parent=app.Nodes(cnode1).Text;
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',{ref1,lifinfo},'Icon','image.png','Tag',lifinfo.datatype,'UserData',cnode);
                        else
                            cnode=cnode-1;
                        end
                    end
                elseif ~contains(name,'iomanagerconfiguation','IgnoreCase',true) && ~contains(name,'environmentalgraph','IgnoreCase',true)
                    s=xml2struct(lifinfo.XLIFFile);
                    lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                    lifinfo.datatype='Image';
                    lifinfo.Parent='';
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',{ref,lifinfo},'Icon','image.png','Tag',lifinfo.datatype,'UserData',cnode);
                else
                    cnode=cnode-1;
                end
            end
            setLogD(app,'Ready reading XLEF file');
            app.Tree.Enable='off';            
            %expand(app.Tree,'all');
        end
        
        function [XLfile]=getXLfile(app, sfile, sparent)
            if ispc
                NET.addAssembly('System.Web');
                sfile=char(System.Web.HttpUtility.UrlDecode(replace(sfile,'+','%2b')));
            else
                sfile=cfUrldecode(replace(sfile,'+','%2b'));
            end
            if ~isempty(sparent)>0
                i=strfind(sparent,'\');
                sfile=strrep(sfile,'..\',[extractBefore(sparent,i(end-1)) '\']);
            end
            sfile=strrep(sfile,'.\',app.filepath);
            XLfile=sfile;
        end
        
        function [LOFfile]=getLOFfile(~, sfile)
            [~, name, ~]=fileparts(sfile);
            i=strfind(sfile,'\');
            sfile=[extractBefore(sfile,i(end-1)) '\' name '.lof'];
            LOFfile=sfile;
        end
        
        function ShowImage(app, im, ax, iminfo, lut)
            switch ax
                case 1; axes=app.UIAxes_ch1;
                case 2; axes=app.UIAxes_ch2;
                case 3; axes=app.UIAxes_ch3;
                case 4; axes=app.UIAxes_ch4;
                case 5; axes=app.UIAxes_ch5;
                case 6; axes=app.UIAxes_ch6;
                case 7; axes=app.UIAxes_ch7;
                case 8; axes=app.UIAxes_ch8;
                case 9; axes=app.UIAxes_ch9;
            end        
            set(axes,'xlimMode','auto','ylimMode','auto')
            cla(axes, 'reset')
            axes.Visible='on';
            if contains(lut,'glow'); lut='hot'; end
            if isempty(lut); lut='grey'; end
            if app.AutoContrButton.Value; im=cfAutocontrast(im); end
            imshow(im,[],'Parent',axes,'InitialMagnification','fit','Border','tight','Colormap',AdvancedColormap(lut));
            if axes.XLim==axes.YLim
                xlim(axes,[1 iminfo.xs]);
                ylim(axes,[1 iminfo.ys]);            
            end
            axes.Tag='image';
            axes.UserData=[iminfo.xs, iminfo.ys];
        end

        function ShowEventlist(~, ev, axes)
            set(axes,'xlimMode','auto','ylimMode','auto')
            cla(axes, 'reset')
            axes.Visible='on';
            scatter(axes,ev.X1, ev.Y1, 1, 'white', 'filled')
            set(axes,'Color','k');          
        end

        
        function HideAxes(~, axes)
            cla(axes, 'reset')
            axes.Visible='off'; 
        end        

    end
    
    methods (Access = public)
        
        function ReArangeAxis(app,telch)
            if telch<1;HideAxes(app,app.UIAxes_ch1);end
            if telch<2;HideAxes(app,app.UIAxes_ch2);end
            if telch<3;HideAxes(app,app.UIAxes_ch3);end
            if telch<4;HideAxes(app,app.UIAxes_ch4);end
            if telch<5;HideAxes(app,app.UIAxes_ch5);end
            if telch<6;HideAxes(app,app.UIAxes_ch6);end
            if telch<7;HideAxes(app,app.UIAxes_ch7);end
            if telch<8;HideAxes(app,app.UIAxes_ch8);end
            if telch<9;HideAxes(app,app.UIAxes_ch9);end
            drawnow();
            pause(1)
            
            w=app.ZSlider.Position(1)-3;
            b=app.TimeSlider.Position(2)+3;
            h=app.TabGroup.Position(4)-b-48-3;
            l=3;
            
            if telch==1
                app.UIAxes_ch1.Position=[l b w h];
                app.UIAxes_ch2.Position=[1 1 1 1];
                app.UIAxes_ch3.Position=[1 1 1 1];
                app.UIAxes_ch4.Position=[1 1 1 1];
                app.UIAxes_ch5.Position=[1 1 1 1];
                app.UIAxes_ch6.Position=[1 1 1 1];
                app.UIAxes_ch7.Position=[1 1 1 1];
                app.UIAxes_ch8.Position=[1 1 1 1];
                app.UIAxes_ch9.Position=[1 1 1 1];
            end
            if telch>1 && telch<=4
                app.UIAxes_ch1.Position=[l b+floor(h/2) floor(w/2) floor(h/2)];
                app.UIAxes_ch2.Position=[l+floor(w/2) b+floor(h/2) floor(w/2) floor(h/2)];
                app.UIAxes_ch3.Position=[l b floor(w/2) floor(h/2)];
                app.UIAxes_ch4.Position=[l+floor(w/2) b floor(w/2) floor(h/2)];
                app.UIAxes_ch5.Position=[1 1 1 1];
                app.UIAxes_ch6.Position=[1 1 1 1];
                app.UIAxes_ch7.Position=[1 1 1 1];
                app.UIAxes_ch8.Position=[1 1 1 1];
                app.UIAxes_ch9.Position=[1 1 1 1];
            end            
            if telch>4 
                app.UIAxes_ch1.Position=[l b+2*floor(h/3) floor(w/3) floor(h/3)];
                app.UIAxes_ch2.Position=[l+floor(w/3) b+2*floor(h/3) floor(w/3) floor(h/3)];
                app.UIAxes_ch3.Position=[l+2*floor(w/3) b+2*floor(h/3) floor(w/3) floor(h/3)];
                app.UIAxes_ch4.Position=[l b+floor(h/3) floor(w/3) floor(h/3)];
                app.UIAxes_ch5.Position=[l+floor(w/3) b+floor(h/3) floor(w/3) floor(h/3)];
                app.UIAxes_ch6.Position=[l+2*floor(w/3) b+floor(h/3) floor(w/3) floor(h/3)];
                app.UIAxes_ch7.Position=[l b floor(w/3) floor(h/3)];
                app.UIAxes_ch8.Position=[l+floor(w/3) b floor(w/3) floor(h/3)];
                app.UIAxes_ch9.Position=[l+2*floor(w/3) b floor(w/3) floor(h/3)];
            end
        end

        function DoShowEventlist(app,lifinfo)
            [result, serror, iminfo] = cfReadMetaData(lifinfo, 1); %#ok<ASGLU> 
            ReArangeAxis(app,1);
            eventlist=cfReadEventList(lifinfo,iminfo);
            ShowEventlist(app,eventlist,app.UIAxes_ch1)
        end        
        
        function DoShowImage(app,lifinfo)
            if app.ShowButton.Value
                if strcmpi(lifinfo.datatype,'Image')
                    [result, serror, iminfo] = cfReadMetaData(lifinfo, app.TileSlider.Value); %#ok<ASGLU> 
                    showmip=app.MaxProjButton.Value && (iminfo.zs>1);
                    telch=0;
                    for ch=1:iminfo.channels
                        if showmip
                            im=cfReadIm3D(lifinfo,iminfo,ch,1,iminfo.zs,app.TimeSlider.Value,app.TileSlider.Value);
                            im=cfMaxprojection(im);
                        else
                            im=cfReadIm(lifinfo,iminfo,ch,app.ZSlider.Value,app.TimeSlider.Value,app.TileSlider.Value);
                        end
                        if ch==1 && app.Ch_1.Value
                            telch=telch+1;
                            ShowImage(app,im,telch,iminfo, iminfo.lutname{ch})
                        end
                        if ch==2 && app.Ch_2.Value
                            telch=telch+1;
                            ShowImage(app,im,telch,iminfo, iminfo.lutname{ch})
                        end
                        if ch==3 && app.Ch_3.Value
                            telch=telch+1;
                            ShowImage(app,im,telch,iminfo, iminfo.lutname{ch})
                        end
                        if ch==4 && app.Ch_4.Value
                            telch=telch+1;
                            ShowImage(app,im,telch,iminfo, iminfo.lutname{ch})
                        end
                        if ch==5 && app.Ch_5.Value
                            telch=telch+1;
                            ShowImage(app,im,telch,iminfo, iminfo.lutname{ch})
                        end
                        if ch==6 && app.Ch_6.Value
                            telch=telch+1;
                            ShowImage(app,im,telch,iminfo, iminfo.lutname{ch})
                        end
                        if ch==7 && app.Ch_7.Value
                            telch=telch+1;
                            ShowImage(app,im,telch,iminfo, iminfo.lutname{ch})
                        end
                        if ch==8 && app.Ch_8.Value
                            telch=telch+1;
                            ShowImage(app,im,telch,iminfo, iminfo.lutname{ch})
                        end
                        if ch==9 && app.Ch_9.Value
                            telch=telch+1;
                            ShowImage(app,im,telch,iminfo, iminfo.lutname{ch})
                        end
                    end
                    if iminfo.zs>1
                        app.ZSlider.Visible='on';
                    end
                    if iminfo.tiles>1
                        app.TileSlider.Visible='on';
                    end
                    if iminfo.ts>1
                       app.TimeSlider.Visible='on';
                    end                        
                    ReArangeAxis(app,telch);
                    app.TabGroup.SelectedTab=app.ImageTab;
                    if iminfo.zs>1
                        if iminfo.tiles>1
                            setLog(app, convertStringsToChars("Image XYZ: (" + iminfo.xs + "x" + iminfo.ys + "x" + iminfo.zs +")" + " Z: " + app.ZSlider.Value + " Tile: " + app.TileSlider.Value))
                        else
                            setLog(app, convertStringsToChars("Image XYZ: (" + iminfo.xs + "x" + iminfo.ys + "x" + iminfo.zs +")" + " Z: " + app.ZSlider.Value))
                        end
                    else
                        setLog(app, convertStringsToChars("Image XY: (" + iminfo.xs + "x" + iminfo.ys + ")"))
                    end
                end
            end
        end        
        
        function setImage(app, im, ax, map, doac)
            if nargin<5
                doac=true;
            end
            switch ax
                case 1; axes=app.UIAxes_1;
                case 2; axes=app.UIAxes_2;
                case 3; axes=app.UIAxes_3;
                case 4; axes=app.UIAxes_4;
                case 5; axes=app.UIAxes_5;
                case 6; axes=app.UIAxes_6;
                case 7; axes=app.UIAxes_7;
                case 8; axes=app.UIAxes_8;
                case 9; axes=app.UIAxes_9;
            end
            set(axes,'xlimMode','auto','ylimMode','auto')
            cla(axes, 'reset')
            axes.Visible='on'; 
            if size(im,3)==1
                if app.AutoContrButton.Value && doac; im=cfAutocontrast(im); end
                imshow(im,[],'Parent',axes,'InitialMagnification','fit','Colormap',map);
            else
                imshow(im,[],'Parent',axes,'InitialMagnification','fit');
            end
            if axes.XLim==axes.YLim
                xlim(axes,[1 size(im,2)]);
                ylim(axes,[1 size(im,1)]); 
            end
            axes.Tag='image';
            axes.UserData=[size(im,2), size(im,1)];
        end        

        
        function setLog(app, newlog)
            newlog=replace(newlog,'_','_ ');
            newlog=replace(newlog,'\','\ ');
            newlog=replace(newlog,'/','/ ');
            cutv=77;
            t=datetime;
            t.Format='HH:mm:ss';
            pspace=strfind(newlog,' ');
            tc=[char(t) '> '];
            if ~isempty(pspace)
                if pspace(1)==1
                    tc='                ';
                    %cutv=55;
                    cutv=87;
                end
            end
            newLine = [tc newlog];
            n=numel(app.LogBox.Items);
            newLine2='';
            if length(newLine)>cutv
                pspace=strfind(extractBefore(newLine,cutv),' ');
                if ~isempty(pspace); cutv=pspace(end); end
                if cutv>1; newLine2=extractAfter(newLine,cutv-1); end
                newLine=extractBefore(newLine,cutv);
            end
            if n<500
                app.LogBox.Items=[app.LogBox.Items(:); newLine];
            else
                app.LogBox.Items=[app.LogBox.Items(n-499:end) newLine];
            end
            drawnow;
            app.LogBox.Value=newLine;
            drawnow;
            scroll(app.LogBox,'bottom');
            drawnow;
            if ~isempty(newLine2); setLog(app, newLine2); end
        end
        
        function setLogD(app, newlog)
            if app.MoredetailsCheckBox.Value
            newlog=replace(newlog,'_','_ ');
            newlog=replace(newlog,'\','\ ');
            newlog=replace(newlog,'/','/ ');
            cutv=77;
                t=datetime;
                t.Format='HH:mm:ss';
                pspace=strfind(newlog,' ');
                tc=[char(t) '> '];
                if ~isempty(pspace)
                    if pspace(1)==1
                        tc='                ';
                        %cutv=55;
                        cutv=87;
                    end
                end
                newLine = [tc newlog];
                n=numel(app.LogBox.Items);
                newLine2='';
                if length(newLine)>cutv
                    pspace=strfind(extractBefore(newLine,cutv),' ');
                    if ~isempty(pspace); cutv=pspace(end); end
                    if cutv>1; newLine2=extractAfter(newLine,cutv-1); end
                    newLine=extractBefore(newLine,cutv);
                end
                if n<500
                    app.LogBox.Items=[app.LogBox.Items(:); newLine];
                else
                    app.LogBox.Items=[app.LogBox.Items(n-499:end) newLine];
                end
                drawnow;
                app.LogBox.Value=newLine;
                drawnow;
                scroll(app.LogBox,'bottom');
                drawnow
                if ~isempty(newLine2); setLogD(app, newLine2); end
            end
        end
        
        function setLogDD(app, newlog)
            newlog=replace(newlog,'_','_ ');
            newlog=replace(newlog,'\','\ ');
            newlog=replace(newlog,'/','/ ');        
            newLine = char(newlog);
            n=numel(app.LogBox.Items);
            newLine2='';
            %cutv=45;
            cutv=77;
            if length(newLine)>cutv
                 pspace=strfind(extractBefore(newLine,cutv),' ');
                 if ~isempty(pspace)
                     cutv=pspace(end);
                 else
                     pspace=strfind(extractBefore(newLine,cutv),'/');
                     if ~isempty(pspace); cutv=pspace(end); end
                 end
                if cutv>1; newLine2=extractAfter(newLine,cutv-1); end
                newLine=extractBefore(newLine,cutv);
            end
            if n<500
                app.LogBox.Items=[app.LogBox.Items(:); newLine];
            else
                app.LogBox.Items=[app.LogBox.Items(n-499:end) newLine];
            end
            drawnow;
            scroll(app.LogBox,'bottom');
            drawnow
            if ~isempty(newLine2); setLogDD(app, newLine2); end
        end
        
        function hideImageAxis(app)
            HideAxes(app,app.UIAxes_ch1);            
            HideAxes(app,app.UIAxes_ch2);            
            HideAxes(app,app.UIAxes_ch3);            
            HideAxes(app,app.UIAxes_ch4);     
            HideAxes(app,app.UIAxes_ch5);     
            HideAxes(app,app.UIAxes_ch6);     
            HideAxes(app,app.UIAxes_ch7);     
            HideAxes(app,app.UIAxes_ch8);     
            HideAxes(app,app.UIAxes_ch9);            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            pause(1);
            setLog(app,'Init GUI');
            hideImageAxis(app);  
            app.ZSlider.Visible='off';
            app.TimeSlider.Visible='off';
            app.TileSlider.Visible='off';
            app.MaxProjButton.Enable='off';
            app.AutoContrButton.Enable='off';
            app.Node.delete;
            drawnow;
            pause(1);
            setLog(app,'Startup Ready');
            drawnow;
            pause(1);
        end

        % Button pushed function: SelectFile
        function SelectFileButtonPushed(app, event)
            [filenames,app.filepath]=uigetfile({'*.xlef;*.lif','Leica LAS-X Files'},'Select XLEF or LIF File',app.filepath,'MultiSelect','on');
            if app.filepath==0
                app.filepath='';
                return;
            end
            if ~iscell(filenames)
                app.filename=filenames;
                app.file=[app.filepath app.filename];
                [~,app.filebasename,app.filetype]=fileparts(app.file);
                if cfCheckForUNCFolder(app.filepath)
                    setLog(app,'Data on mapped network folder!');
                    setLog(app,'Analyzing will be slower.');
                end
                if strcmpi(app.filetype,".xlef")    
                    ReadLeicaXLEF(app);
                end
                if strcmpi(app.filetype,".lif")
                    ReadLeicaLIF(app);
                end
            else
                for k=1:numel(filenames)
                    app.filename=filenames{k};
                    app.file=[app.filepath app.filename];
                    [~,app.filebasename,app.filetype]=fileparts(app.file);
                    if cfCheckForUNCFolder(app.filepath)
                        setLog(app,'Data on mapped network folder!');
                        setLog(app,'Analyzing will be slower.');
                    end
                    if strcmpi(app.filetype,".xlef")    
                        ReadLeicaXLEF(app);
                    end
                    if strcmpi(app.filetype,".lif")
                        ReadLeicaLIF(app);
                    end                    
                end
            end
            app.Tree.Enable='on';
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if numel(selectedNodes) > 1
                hideImageAxis(app);  
                app.ZSlider.Visible='off';
                app.MaxProjButton.Enable='off';
                app.AutoContrButton.Enable='off';
                app.TimeSlider.Visible='off';
                app.TileSlider.Visible='off';
                drawnow;                
                app.clifinfo=[];
            else
                if iscell(selectedNodes.NodeData)
                    lifinfo=selectedNodes.NodeData{2};
                    [result, serror, iminfo] = cfReadMetaData(lifinfo, 1);
                    if result
                        app.clifinfo=lifinfo;
                        if strcmp(app.clifinfo.datatype,'Image')
                            app.filepath=lifinfo.filepath;
                            setLogD(app,' ');
                            setLogD(app,['MicType: ' iminfo.mictype]);
                            setLogD(app,['Channels: ' num2str(iminfo.channels) ', Bytes/Pixel: ' num2str(iminfo.xbytesinc)]);
                            setLogD(app,['Xsize: ' num2str(iminfo.xs) ', Ysize: ' num2str(iminfo.ys) ', Zsize: ' num2str(iminfo.zs) ', Tiles: ' num2str(iminfo.tiles)]);
                            setLogD(app,['XY PixelSize (µm): ' num2str(iminfo.xres2) ', Z PixelSize (µm): ' num2str(iminfo.zres2)]);
                            setLogD(app,['Time frames: ' num2str(iminfo.ts) ', Time interval: ' num2str(minutes(seconds(iminfo.tres))) ' min']);
                            setLogD(app,['Objective: ' iminfo.objective ' NA: ' num2str(iminfo.na)]);
                            setLogD(app,['Objective-Refractiveindex: ' num2str(iminfo.refractiveindex)]);
                            for k=1:iminfo.channels
                                setLogD(app,['Channel ' num2str(k) ' ,FilterBlock: ' char(iminfo.filterblock(k)) ]);
                                setLogD(app,['Exc.: ' num2str(iminfo.excitation(k)) '  Em.: ' num2str(iminfo.emission(k))]);
                            end
                            app.AutoContrButton.Enable='on';
                            app.AllButton.Visible=cfV(iminfo.channels>1);
                            if iminfo.channels==1; app.Ch_1.Value=true; end
                            app.Ch_1.Visible=cfV(iminfo.channels>1);
                            app.Ch_2.Visible=cfV(iminfo.channels>1);
                            app.Ch_3.Visible=cfV(iminfo.channels>2);
                            app.Ch_4.Visible=cfV(iminfo.channels>3);
                            app.Ch_5.Visible=cfV(iminfo.channels>4);
                            app.Ch_6.Visible=cfV(iminfo.channels>5);
                            app.Ch_7.Visible=cfV(iminfo.channels>6);
                            app.Ch_8.Visible=cfV(iminfo.channels>7);
                            app.Ch_9.Visible=cfV(iminfo.channels>8);
                            if iminfo.zs>1
                                %setLogD(app,['Z StepSize (µm): ' num2str(iminfo.zres2)])
                                app.ZSlider.Value=1;
                                app.ZSlider.Limits=[1 iminfo.zs];
                                lticks=ones(iminfo.zs,1);
                                for ticks=1:iminfo.zs
                                    lticks(ticks)=ticks;
                                end
                                app.ZSlider.MajorTicks=lticks;
                                app.ZSlider.Value=round(iminfo.zs/2);
                                if app.ShowButton.Value
                                    app.ZSlider.Visible='on';
                                end
                                app.MaxProjButton.Enable='on';
                            else
                                app.MaxProjButton.Enable='off';
                                app.ZSlider.Visible='off';
                                app.ZSlider.Value=1;
                            end
                            if iminfo.tiles>1
                                app.TileSlider.Value=1;
                                app.TileSlider.Limits=[1 iminfo.tiles];
                                if iminfo.tiles<20
                                    lticks=ones(iminfo.tiles,1);
                                    for ticks=1:iminfo.tiles
                                        lticks(ticks)=ticks;
                                    end
                                    app.TileSlider.MajorTicks=lticks;
                                else
                                    app.TileSlider.MajorTicks=[1 round(iminfo.tiles/4) round(iminfo.tiles/2) 3*round(iminfo.tiles/4) iminfo.tiles];
                                end
                                app.TileSlider.Value=round(iminfo.tiles/2);
                                if app.ShowButton.Value
                                    app.TileSlider.Visible='on';
                                end
                            else
                                app.TileSlider.Visible='off';
                                app.TileSlider.Value=1;
                            end
                            if iminfo.ts>1
                                app.TimeSlider.Value=1;
                                app.TimeSlider.Limits=[1 iminfo.ts];
                                if iminfo.ts<20
                                    lticks=ones(iminfo.ts,1);
                                    for ticks=1:iminfo.ts
                                        lticks(ticks)=ticks;
                                    end
                                    app.TimeSlider.MajorTicks=lticks;
                                else
                                    app.TimeSlider.MajorTicks=[1 round(iminfo.ts/4) round(iminfo.ts/2) 3*round(iminfo.ts/4) iminfo.ts];
                                end
                                app.TimeSlider.Value=round(iminfo.ts/2);
                                if app.ShowButton.Value
                                   app.TimeSlider.Visible='on';
                                end
                            else
                                app.TimeSlider.Visible='off';
                                app.TimeSlider.Value=1;
                            end
                            DoShowImage(app, app.clifinfo)
                        elseif strcmp(app.clifinfo.datatype,'Eventlist')
                            setLogD(app,'Reading Metadata Eventlist');
                            setLogD(app,['NumberOfEvents: ' num2str(iminfo.NumberOfEvents)]);
                            setLogD(app,['Threshold: ' num2str(iminfo.Threshold)]);
                            setLogD(app,['Gain: ' num2str(iminfo.Gain)]);
                            setLogD(app,['FieldOfViewX: ' num2str(iminfo.FieldOfViewX)]);
                            setLogD(app,['FieldOfViewY: ' num2str(iminfo.FieldOfViewY)]);
                            app.MaxProjButton.Enable='off';
                            app.AutoContrButton.Enable='off';
                            app.ZSlider.Visible='off';
                            app.ZSlider.Value=1;
                            app.TileSlider.Visible='off';
                            app.TileSlider.Value=1;
                            app.TimeSlider.Visible='off';
                            app.TimeSlider.Value=1;
                            DoShowEventlist(app,app.clifinfo)
                        end
                    else
                        message = sprintf(serror);
                        uialert(app.CI_ImagingLIFUIFigure,message,'Warning', 'Icon','error');
                        setProgress(app, 0, 'Error, File and Info NOT OK');
                        hideImageAxis(app);  
                        app.ZSlider.Visible='off';
                        app.MaxProjButton.Enable='off';
                        app.AutoContrButton.Enable='off';
                        app.TimeSlider.Visible='off';
                        app.TileSlider.Visible='off';
                        drawnow;                
                        app.clifinfo=[];
                    end
                else
                    hideImageAxis(app);  
                    app.ZSlider.Visible='off';
                    app.MaxProjButton.Enable='off';
                    app.AutoContrButton.Enable='off';
                    app.TimeSlider.Visible='off';
                    app.TileSlider.Visible='off';
                    drawnow;                
                    app.clifinfo=[];
                end
            end
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            value = round(app.ZSlider.Value);
            app.ZSlider.Value=value;
            DoShowImage(app, app.clifinfo)           
        end

        % Value changed function: TileSlider
        function TileSliderValueChanged(app, event)
            value = round(app.TileSlider.Value);
            app.TileSlider.Value=value;            
            DoShowImage(app, app.clifinfo)
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = round(app.TimeSlider.Value);
            app.TimeSlider.Value=value;            
            DoShowImage(app, app.clifinfo)
        end

        % Value changed function: AutoContrButton, MaxProjButton
        function MaxButtonValueChanged(app, event)
            DoShowImage(app, app.clifinfo)
        end

        % Value changed function: Ch_1, Ch_2, Ch_3, Ch_4, Ch_5, Ch_6, 
        % ...and 3 other components
        function ChannelsValueChanged(app, event)
            DoShowImage(app, app.clifinfo)            
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            app.Ch_1.Value=true;
            app.Ch_2.Value=true;
            app.Ch_3.Value=true;
            app.Ch_4.Value=true;
            app.Ch_5.Value=true;
            app.Ch_6.Value=true;
            app.Ch_7.Value=true;
            app.Ch_8.Value=true;
            app.Ch_9.Value=true;
            DoShowImage(app, app.clifinfo)            
        end

        % Button pushed function: CloseSelectedFile
        function CloseSelectedFileButtonPushed(app, event)
            tel=1;
            for i=1:numel(app.Tree.SelectedNodes)
                if numel(app.Tree.SelectedNodes)>0 
                    if strcmpi(app.Tree.SelectedNodes(tel).Tag,'File')
                        app.Tree.SelectedNodes(tel).delete
                    else
                        tel=tel+1;
                    end
                end
            end
            if numel(app.Tree.Children)==0
                app.SelectFile.Enable='on';
            end
        end

        % Value changed function: ShowButton
        function ShowButtonValueChanged(app, event)
            if app.ShowButton.Value
                DoShowImage(app, app.clifinfo)            
            else
                app.ZSlider.Visible='off';
                app.TimeSlider.Visible='off';
                app.TileSlider.Visible='off';
                hideImageAxis(app);                  
            end
        end

        % Button pushed function: ExpandAllButton
        function ExpandAllButtonPushed(app, event)
            expand(app.Tree,'all')
        end

        % Button pushed function: ExpandNoneButton
        function ExpandNoneButtonPushed(app, event)
            collapse(app.Tree,'all')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CI_ImagingLIFUIFigure = uifigure('Visible', 'off');
            app.CI_ImagingLIFUIFigure.Name = 'CI_ImagingLIF';
            app.CI_ImagingLIFUIFigure.Icon = 'icon48x48.png';
            app.TabGroup = uitabgroup(app.CI_ImagingLIFUIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.UIAxes_ch1 = uiaxes(app.ImageTab);
            app.UIAxes_ch1.XTick = [];
            app.UIAxes_ch1.XTickLabel = {};
            app.UIAxes_ch1.YTick = [];
            app.UIAxes_ch1.YTickLabel = {};
            app.UIAxes_ch2 = uiaxes(app.ImageTab);
            app.UIAxes_ch2.XTick = [];
            app.UIAxes_ch2.XTickLabel = '';
            app.UIAxes_ch2.YTick = [];
            app.UIAxes_ch2.YTickLabel = '';
            app.UIAxes_ch3 = uiaxes(app.ImageTab);
            app.UIAxes_ch3.XTick = [];
            app.UIAxes_ch3.XTickLabel = '';
            app.UIAxes_ch3.YTick = [];
            app.UIAxes_ch3.YTickLabel = '';
            app.UIAxes_ch4 = uiaxes(app.ImageTab);
            app.UIAxes_ch4.XTick = [];
            app.UIAxes_ch4.XTickLabel = '';
            app.UIAxes_ch4.YTick = [];
            app.UIAxes_ch4.YTickLabel = '';
            app.UIAxes_ch5 = uiaxes(app.ImageTab);
            app.UIAxes_ch5.XTick = [];
            app.UIAxes_ch5.XTickLabel = '';
            app.UIAxes_ch5.YTick = [];
            app.UIAxes_ch5.YTickLabel = '';
            app.UIAxes_ch6 = uiaxes(app.ImageTab);
            app.UIAxes_ch6.XTick = [];
            app.UIAxes_ch6.XTickLabel = '';
            app.UIAxes_ch6.YTick = [];
            app.UIAxes_ch6.YTickLabel = '';
            app.UIAxes_ch7 = uiaxes(app.ImageTab);
            app.UIAxes_ch7.XTick = [];
            app.UIAxes_ch7.XTickLabel = '';
            app.UIAxes_ch7.YTick = [];
            app.UIAxes_ch7.YTickLabel = '';
            app.UIAxes_ch8 = uiaxes(app.ImageTab);
            app.UIAxes_ch8.XTick = [];
            app.UIAxes_ch8.XTickLabel = '';
            app.UIAxes_ch8.YTick = [];
            app.UIAxes_ch8.YTickLabel = '';
            app.UIAxes_ch9 = uiaxes(app.ImageTab);
            app.UIAxes_ch9.XTick = [];
            app.UIAxes_ch9.XTickLabel = '';
            app.UIAxes_ch9.YTick = [];
            app.UIAxes_ch9.YTickLabel = '';
            app.TimeSlider = uislider(app.ImageTab);
            app.TimeSlider.Limits = [0 10];
            app.TimeSlider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.MinorTicks = [];
            app.TileSlider = uislider(app.ImageTab);
            app.TileSlider.Limits = [0 10];
            app.TileSlider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.TileSlider.ValueChangedFcn = createCallbackFcn(app, @TileSliderValueChanged, true);
            app.TileSlider.MinorTicks = [];
            app.ZSlider = uislider(app.ImageTab);
            app.ZSlider.MajorTicks = [0 3 6 9 12 15 18 21 24 27 30 33 36 39 42 45 48 51 54 57 60 63 66 69 72 75 78 81 84 87 90 93 96 100];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.MinorTicks = [];
            app.Ch_1 = uibutton(app.ImageTab, 'state');
            app.Ch_1.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
            app.Ch_1.Tooltip = {''};
            app.Ch_1.Text = 'Ch1';
            app.Ch_1.Value = true;
            app.Ch_2 = uibutton(app.ImageTab, 'state');
            app.Ch_2.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
            app.Ch_2.Tooltip = {''};
            app.Ch_2.Text = 'Ch2';
            app.Ch_2.Value = true;
            app.Ch_3 = uibutton(app.ImageTab, 'state');
            app.Ch_3.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
            app.Ch_3.Tooltip = {''};
            app.Ch_3.Text = 'Ch3';
            app.Ch_3.Value = true;
            app.Ch_4 = uibutton(app.ImageTab, 'state');
            app.Ch_4.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
            app.Ch_4.Tooltip = {''};
            app.Ch_4.Text = 'Ch4';
            app.Ch_4.Value = true;
            app.Ch_5 = uibutton(app.ImageTab, 'state');
            app.Ch_5.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
            app.Ch_5.Tooltip = {''};
            app.Ch_5.Text = 'Ch5';
            app.Ch_5.Value = true;
            app.Ch_6 = uibutton(app.ImageTab, 'state');
            app.Ch_6.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
            app.Ch_6.Tooltip = {''};
            app.Ch_6.Text = 'Ch6';
            app.Ch_6.Value = true;
            app.Ch_7 = uibutton(app.ImageTab, 'state');
            app.Ch_7.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
            app.Ch_7.Tooltip = {''};
            app.Ch_7.Text = 'Ch7';
            app.Ch_7.Value = true;
            app.Ch_8 = uibutton(app.ImageTab, 'state');
            app.Ch_8.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
            app.Ch_8.Tooltip = {''};
            app.Ch_8.Text = 'Ch8';
            app.Ch_8.Value = true;
            app.Ch_9 = uibutton(app.ImageTab, 'state');
            app.Ch_9.ValueChangedFcn = createCallbackFcn(app, @ChannelsValueChanged, true);
            app.Ch_9.Tooltip = {''};
            app.Ch_9.Text = 'Ch9';
            app.Ch_9.Value = true;
            app.AllButton = uibutton(app.ImageTab, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.LogBox = uilistbox(app.CI_ImagingLIFUIFigure);
            app.LogBox.Items = {};
            app.LogBox.Value = {};
            app.LogLabel = uilabel(app.CI_ImagingLIFUIFigure);
            app.LogLabel.Text = 'Log';
            app.MoredetailsCheckBox = uicheckbox(app.CI_ImagingLIFUIFigure);
            app.MoredetailsCheckBox.Text = 'More details';
            app.MoredetailsCheckBox.Value = true;
            app.TabGroup2 = uitabgroup(app.CI_ImagingLIFUIFigure);
            app.LASXFilesTab = uitab(app.TabGroup2);
            app.LASXFilesTab.Title = 'LAS-X Files';
            app.SelectFile = uibutton(app.LASXFilesTab, 'push');
            app.SelectFile.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFile.Icon = 'LAS-X.png';
            app.SelectFile.Text = 'Add Leica LAS-X File';
            app.Tree = uitree(app.LASXFilesTab);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Enable = 'off';
            app.Node = uitreenode(app.Tree);
            app.Node.NodeData = 'Root';
            app.Node.Text = 'Node';
            app.CloseSelectedFile = uibutton(app.LASXFilesTab, 'push');
            app.CloseSelectedFile.ButtonPushedFcn = createCallbackFcn(app, @CloseSelectedFileButtonPushed, true);
            app.CloseSelectedFile.Text = 'Close Selected';
            app.ExpandAllButton = uibutton(app.LASXFilesTab, 'push');
            app.ExpandAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandAllButtonPushed, true);
            app.ExpandAllButton.Icon = 'Expand.png';
            app.ExpandAllButton.Text = '';
            app.ExpandNoneButton = uibutton(app.LASXFilesTab, 'push');
            app.ExpandNoneButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandNoneButtonPushed, true);
            app.ExpandNoneButton.Icon = 'Collapse.png';
            app.ExpandNoneButton.Text = '';
            app.ViewPanel = uipanel(app.LASXFilesTab);
            app.ViewPanel.Title = 'View';
            app.AutoContrButton = uibutton(app.ViewPanel, 'state');
            app.AutoContrButton.ValueChangedFcn = createCallbackFcn(app, @MaxButtonValueChanged, true);
            app.AutoContrButton.Tooltip = {''};
            app.AutoContrButton.Text = 'AutoContr';
            app.MaxProjButton = uibutton(app.ViewPanel, 'state');
            app.MaxProjButton.ValueChangedFcn = createCallbackFcn(app, @MaxButtonValueChanged, true);
            app.MaxProjButton.Tooltip = {''};
            app.MaxProjButton.Text = 'MaxProj';
            app.ShowButton = uibutton(app.ViewPanel, 'state');
            app.ShowButton.ValueChangedFcn = createCallbackFcn(app, @ShowButtonValueChanged, true);
            app.ShowButton.Tooltip = {''};
            app.ShowButton.Text = 'Show';
            app.ShowButton.Value = true;
            % Show the figure after all components are created
            app.CI_ImagingLIFUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CI_ImagingLIF(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CI_ImagingLIFUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CI_ImagingLIFUIFigure)
        end
    end
end